classdef Thresholding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SemiAutoThresholdingUIFigure    matlab.ui.Figure
        SaveButton                      matlab.ui.control.Button
        SavingPathButton                matlab.ui.control.Button
        SavingPathEditField             matlab.ui.control.EditField
        K2DButton                       matlab.ui.control.Button
        D2KButton                       matlab.ui.control.Button
        TKeepListBox                    matlab.ui.control.ListBox
        TDiscListBox                    matlab.ui.control.ListBox
        ListofParadigmsListBox          matlab.ui.control.ListBox
        ListofParadigmsListBoxLabel     matlab.ui.control.Label
        NameofParadigmEditField         matlab.ui.control.EditField
        NameofParadigmEditFieldLabel    matlab.ui.control.Label
        ApplyParadigmButton             matlab.ui.control.Button
        ParadigmwindowhighEditField     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        ParadigmwindowlowEditField      matlab.ui.control.NumericEditField
        BaselineLabel                   matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        BaselineTab                     matlab.ui.container.Tab
        ThresholdoutnegativeaveragebaselineCheckBox  matlab.ui.control.CheckBox
        ExclusionThresholdEditField     matlab.ui.control.NumericEditField
        ExclusionThresholdLabel         matlab.ui.control.Label
        BaselineWindowHighEditField     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        BaselineWindowLowEditField      matlab.ui.control.NumericEditField
        BaselineWindowEditField_2Label  matlab.ui.control.Label
        ABCsTab                         matlab.ui.container.Tab
        WindowformovingEditField        matlab.ui.control.NumericEditField
        WindowformovingEditFieldLabel   matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeLabel                       matlab.ui.control.Label
        DriftEditField                  matlab.ui.control.NumericEditField
        UpDriftLabel                    matlab.ui.control.Label
        OrderofPolyfitEditField         matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        ABCs_Plot                       matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        ABCsCheckBox                    matlab.ui.control.CheckBox
        BaselineCheckBox                matlab.ui.control.CheckBox
        NameofConditionEditField        matlab.ui.control.EditField
        NameofConditionEditFieldLabel   matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        BrowseFileButton                matlab.ui.control.Button
        TDiscPlot                       matlab.ui.control.UIAxes
        TKeepPlot                       matlab.ui.control.UIAxes
    end

    % -------------------------------------------------------------------------
    % 
    % 
    % 
    % 
    % 
    % 
    % -------------------------------------------------------------------------

    properties (Access = public)
        c_name = {};        % condition name
        file_names = {};    % file names
        paths = {};         % file paths
        matrix = [];        % trace matrix
        trace_names = {};   % name of traces (neuron #)
        p_names_part = {};  % paradigm names (w/o frame numbers)
        p_names_full = {};  % full paradigm names (w.frame numbers)
        p_cnt = 0;          % paradigm count
        paradigms = {};     % paradigm trace matrices
        p_current = [];     % active paradigm trace matrix
        p_currenti = [];    % active paradigm trace matrix index
        keepind_Base = {};
        keepind_ABCs = {};
%         keepind_AUC = {};
        keepind = {};
        discind = {};
        keep_slci = [];
        disc_slci = [];
        save_folder = '';
    end
    
    methods (Access = public)

        % -------------------------------------------------------------------------
        % Read complied Excel sheet
        % Input:  skipcol1 - skip the first column (indeces) if true
        % Output: file - 
        %         path - 
        %         rawData - 
        %         numericMatrix - 
        % -------------------------------------------------------------------------
        function [file,path,rawData,numericMatrix] = read_data(~,skipcol1)
            rawData = []; numericMatrix = [];
            [file, path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'},'Select an Excel file');
            if isequal(file, 0) || isequal(path, 0)
                return
            else
                excelFilePath = fullfile(path, file);
                [~, ~, ext] = fileparts(file);
                numericMatrix = [];
                switch lower(ext)
                    case {'.xlsx', '.xls'}
                        rawData = readtable(excelFilePath,'VariableNamingRule','preserve');
                        try
                            numericMatrix = table2array(rawData);
                        catch
                            disp('Non-numeric data encountered. Check your Excel file.');
                            numericMatrix = [];
                        end           
                    otherwise
                        disp(['Unsupported file type: ', ext]);
                end
            end
            if skipcol1
                numericMatrix = numericMatrix(:,2:end);
            end
            % remove columns with nan(s)
            [rawData,~] = rmmissing(rawData,2,'MinNumMissing',1);
            [numericMatrix,~] = rmmissing(numericMatrix,2,'MinNumMissing',1);
        end
        
        % -------------------------------------------------------------------------
        % Normalize intensity traces btween 0 and 1
        % (substitute subzero values with 0)
        % Input:  numericMatrix - intensities matrix
        % Output: norm_ints - normalized intensities
        % -------------------------------------------------------------------------
        function norm_ints = normalization(~,numericMatrix)
            max_int = max(numericMatrix,[],1);
            norm_ints = numericMatrix./max_int;
            norm_ints(norm_ints<0) = 0; % set negative intensities to 0
        end

        % -------------------------------------------------------------------------
        % Primary method: baseline spontaneous response thresholding
        % 
        % -------------------------------------------------------------------------
        function keepi = baseline_thres(app,numericMatrix,window,thres,xnegi)
            baseline = normalization(app,numericMatrix);
            baseline = baseline(window(1):window(2),:);
            keepi = find(max(baseline,[],1)<=thres);
            if xnegi==1
                disci = find(mean(numericMatrix(window(1):window(2),:))<0);
                keepi = setdiff(keepi,disci,'stable');
            end
        end

        % -------------------------------------------------------------------------
        % Primary method: area between curves
        % Steps:
        % 1. fit a 'n'th order polynomial to each trace
        % 2. calculate absolute area between raw and fitted traces (abcs)
        % 3. apply threshold values on abcs
        % Options: Summation - abcs about the entire trace
        %          Moving Max - abcs about a defined moving window; maximum
        %                       value was returned for each trace
        % Input:  numericMatrix - intensities matrix
        %         order - order of polynimial fit
        %         window - =0, Summation
        %                - >0, Moving Max
        % Output: abcs - absolute area between curves for each trace
        %         goodness - goodness of polynomial fit
        % -------------------------------------------------------------------------
        function [abcs,goodness] = ABCs(~,numericMatrix,order,window)
            m = width(numericMatrix); n = height(numericMatrix);
            fitted = zeros(n,m);
            goodness = cell(1,m);
            ftp = cell(1,order+1); ftp{1} = '1';
            for i = 1:order
                ftp{i+1} = ['x^',num2str(i)];
            end
            ftp = fittype(ftp);
            for i=1:m
                [temp,goodness{i}] = fit((1:n)',numericMatrix(:,i),ftp);
                fitted(:,i) = temp((1:n)');
            end
            if window==0 % default (absolute sum) mode
                abcs = trapz(abs(numericMatrix-fitted),1);
            elseif window>0 % moving (absolute max) mode
                o = n-window+1;
                abcs = zeros(o,m);
                for i = 1:o
                    seg_raw = numericMatrix(i:i+window-1,:);
                    seg_fit = fitted(i:i+window-1,:);
                    abcs(i,:) = trapz(abs(seg_raw-seg_fit),1);
                end
                abcs = max(abcs,[],1);
            end
        end

        % -------------------------------------------------------------------------
        % Primary method: area under the curve
        % 
        % -------------------------------------------------------------------------
        function auc = AUC(app,numericMatrix,window)
            m = width(numericMatrix); n = height(numericMatrix);
            numericMatrix(numericMatrix<0) = 0;
            if window==0 % default (absolute sum) mode
                auc = trapz(numericMatrix,1);
            elseif window>0 % moving (absolute max) mode
                o = n-window+1;
                auc = zeros(o,m);
                for i = 1:o
                    seg_raw = numericMatrix(i:i+window-1,:);
                    auc(i,:) = trapz(seg_raw,1);
                end
                auc = max(auc,[],1);
            end
        end
        
        % -------------------------------------------------------------------------
        % Update Baseline preview
        % -------------------------------------------------------------------------
        function update_Base(app)
            st = app.BaselineWindowLowEditField.Value;
            ed = app.BaselineWindowHighEditField.Value;
            thres = app.ExclusionThresholdEditField.Value/100;
            xnegi = app.ThresholdoutnegativeaveragebaselineCheckBox.Value;
            if st>ed || st<1 || ed>height(app.p_current) || thres<0 || thres>100
                disp('Please check input values.')
                return
            else
                keep = baseline_thres(app,app.p_current,[st,ed],thres,xnegi);
                app.keepind_Base{app.p_currenti} = keep;
                update_inds(app)
                update_plots(app)
            end
        end
        
        % -------------------------------------------------------------------------
        % Update ABCs preview
        % -------------------------------------------------------------------------
        function update_ABCs(app)
            order = app.OrderofPolyfitEditField.Value;
            window = app.WindowformovingEditField.Value;
            if order<0 || window<0 || window > height(app.p_current)
                return
            else
                if order==0
                    abcs = AUC(app,app.p_current,window);
                else
                    [abcs,~] = ABCs(app,app.p_current,order,window);
                end
                bar = mean(abcs);
                if app.DriftEditField.Value>=0 && app.DriftEditField.Value<=100
                    bar = bar+(max(abcs)-bar)*app.DriftEditField.Value/100;
                elseif app.DriftEditField.Value<0 && app.DriftEditField.Value>=-100
                    bar = bar+(bar-min(abcs))*app.DriftEditField.Value/100;
                else
                    app.DriftEditField.Value = 0;
                end
                axis(app.ABCs_Plot,'auto')
                swarmchart(app.ABCs_Plot,ones(size(abcs)),abcs)
                hold(app.ABCs_Plot,'on')
                yline(app.ABCs_Plot,bar,'r')
                hold(app.ABCs_Plot,'off') 
                keep = find(abcs>=bar);
                app.keepind_ABCs{app.p_currenti} = keep;
                update_inds(app)
                update_plots(app)
            end
        end

        % -------------------------------------------------------------------------
        % Update keep & discard indexes
        % -------------------------------------------------------------------------
        function update_inds(app)
            keep = 1:width(app.matrix);
            if app.BaselineCheckBox.Value
                keep = app.keepind_Base{app.p_currenti};
                if app.ABCsCheckBox.Value
                    keep = intersect(keep,...
                                 app.keepind_ABCs{app.p_currenti},'stable');
                end
%                 if app.AUCCheckBox.Value
%                     keep = intersect(keep,...
%                                  app.keepind_AUC{app.p_currenti},'stable');
%                 end
            elseif app.ABCsCheckBox.Value
                keep = app.keepind_ABCs{app.p_currenti};
%                 if app.AUCCheckBox.Value
%                     keep = intersect(keep,...
%                                  app.keepind_AUC{app.p_currenti},'stable');
%                 end
%             elseif app.AUCCheckBox.Value
%                 keep = app.keepind_AUC{app.p_currenti};
            end
            app.keepind{app.p_currenti} = keep;
            disc = setdiff(1:width(app.matrix),keep,'stable');
            app.discind{app.p_currenti} = disc;
        end

        % -------------------------------------------------------------------------
        % Update mannual refinement lists & plots
        % -------------------------------------------------------------------------
        function update_plots(app)
            keep = app.keepind{app.p_currenti};
            disc = app.discind{app.p_currenti};
            % Update mannual refinement selection
            app.TKeepListBox.Items = app.trace_names(keep);
            app.TDiscListBox.Items = app.trace_names(disc);
            [~,app.keep_slci] = ismember(app.TKeepListBox.Value,...
                                         app.TKeepListBox.Items);
            [~,app.disc_slci] = ismember(app.TDiscListBox.Value,...
                                         app.TDiscListBox.Items);
            % Update plots
            cla(app.TKeepPlot)
            axis(app.TKeepPlot,'auto')
            plot(app.TKeepPlot,app.p_current(:,keep),'k')
            if ~isempty(app.keep_slci)
                hold(app.TKeepPlot,'on')
                plot(app.TKeepPlot,app.p_current(:,keep(app.keep_slci)),'r')
                hold(app.TKeepPlot,'off')
            end
            cla(app.TDiscPlot)
            axis(app.TDiscPlot,'auto')
            plot(app.TDiscPlot,app.p_current(:,disc),'k')
            if ~isempty(app.disc_slci)
                hold(app.TDiscPlot,'on')
                plot(app.TDiscPlot,app.p_current(:,disc(app.disc_slci)),'r')
                hold(app.TDiscPlot,'off')
            end
        end

        % -------------------------------------------------------------------------
        % Organize and save data
        % -------------------------------------------------------------------------
        function savedata(app)
            keepi = app.keepind{app.p_currenti};
            disci = app.discind{app.p_currenti};
            keep = array2table(app.p_current(:,keepi));
            disc = array2table(app.p_current(:,disci));
            keep_varn = app.trace_names(keepi);
            disc_varn = app.trace_names(disci);
            keep = renamevars(keep,1:width(keep),keep_varn);
            disc = renamevars(disc,1:width(disc),disc_varn);
            keep_filen = [app.p_names_full{app.p_currenti},' - Kept.xlsx'];
            disc_filen = [app.p_names_full{app.p_currenti},' - Discarded.xlsx'];
            writetable(keep,keep_filen)
            writetable(disc,disc_filen)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            UI_width = 1015;
            UI_height = 583;
            screen_pix = get(0,'screensize');
            app.SemiAutoThresholdingUIFigure.Position = [(screen_pix(3)-UI_width)/2,...
                                          (screen_pix(4)-UI_height)/2,...
                                          UI_width,UI_height];
            if isdeployed 
			    % User is running an executable in standalone mode. 
			    [~,result] = system('set PATH');
			    executableFolder = char(regexpi(result,'Path=(.*?);','tokens','once'));
		    else
			    % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [executableFolder,~,~] = fileparts(tmp_path);
            end
            cd(executableFolder)
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [file,path,raw,numericMatrix] = read_data(app,1);
            if isempty(numericMatrix)
                return
            else
                oldpath = cd(path);
                app.file_names = [app.file_names;file];
                app.paths = [app.paths;path];
                app.c_name = app.NameofConditionEditField.Value;
                if isempty(app.c_name)
                    app.c_name = 'Unnamed Condition';
                end
%                 m = height(app.matrix); n = height(numericMatrix);
%                 if m > n
%                     temp = nan(m-n,width(numericMatrix));
%                     numericMatrix = [numericMatrix;temp];
%                 elseif m<n
%                     temp = nan(n-m,width(app.matrix));
%                     app.matrix = [app.matrix;temp];
%                 end
                app.matrix = [app.matrix,numericMatrix];
                app.trace_names = [app.trace_names,...
                                   raw.Properties.VariableNames(2:end)];
                app.UITable.Data = {app.c_name,width(app.matrix)};
                cd(oldpath)
            end
        end

        % Callback function
        function ApplyButton_2Pushed(app, event)

        end

        % Button down function: ABCsTab
        function ABCsTabButtonDown(app, event)
%             update_ABCs(app)
        end

        % Button pushed function: ApplyParadigmButton
        function ApplyParadigmButtonPushed(app, event)
            st = app.ParadigmwindowlowEditField.Value;
            ed = app.ParadigmwindowhighEditField.Value;
            if st>ed || st<1 || ed>height(app.matrix)
                disp('Please check input values.')
                return
            else
                app.p_cnt = app.p_cnt+1;
                app.keepind_Base = [app.keepind_Base,{1:width(app.matrix)}];
                app.keepind_ABCs = [app.keepind_ABCs,{1:width(app.matrix)}];
%                 app.keepind_AUC = [app.keepind_AUC,{1:width(app.matrix)}];
                app.keepind = [app.keepind,{1:width(app.matrix)}];
                app.discind = [app.discind,{[]}];
                temp_matrix = app.matrix(st:ed,:);
                app.paradigms = [app.paradigms,{temp_matrix}];
                new_name = app.NameofParadigmEditField.Value;
                if isempty(new_name) || any(strcmp(app.p_names_part,new_name))
                    new_name = ['Paradigm ',num2str(app.p_cnt)];
                end
                app.p_names_part = [app.p_names_part,new_name];
                new_name = [new_name,', frame ',num2str(st),' to ',num2str(ed)];
                app.p_names_full = [app.p_names_full,new_name];
                app.ListofParadigmsListBox.Items = app.p_names_full;
                app.ListofParadigmsListBox.Value = app.p_names_full{1};
                app.p_current = app.paradigms{1};
                app.p_currenti = 1;
                update_Base(app)
                update_ABCs(app)
                update_inds(app)
                update_plots(app)
            end
        end

        % Value changed function: ListofParadigmsListBox
        function ListofParadigmsListBoxValueChanged(app, event)
            for i = 1:app.p_cnt
                if strcmp(app.ListofParadigmsListBox.Value,app.p_names_full{i})
                    app.p_current = app.paradigms{i};
                    app.p_currenti = i;
                    update_Base(app)
                    update_ABCs(app)
                    update_inds(app)
                    update_plots(app)
                end
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            if strcmp(app.ModeDropDown.Value,'Summation')
                app.WindowformovingEditField.Value = 0;
            else
                app.WindowformovingEditField.Value = 10;
            end
            update_ABCs(app)
        end

        % Value changed function: OrderofPolyfitEditField
        function OrderofPolyfitEditFieldValueChanged(app, event)
            update_ABCs(app)            
        end

        % Value changed function: WindowformovingEditField
        function WindowformovingEditFieldValueChanged(app, event)
            if app.WindowformovingEditField.Value<=0
                if app.WindowformovingEditField.Value<0
                    app.WindowformovingEditField.Value = 0;
                end
                app.ModeDropDown.Value = 'Summation';
            else
                app.ModeDropDown.Value = 'Moving Max';
            end
            update_ABCs(app)
        end

        % Value changed function: DriftEditField
        function DriftEditFieldValueChanged(app, event)
            update_ABCs(app)
        end

        % Button down function: BaselineTab
        function BaselineTabButtonDown(app, event)
%             update_Base(app)
        end

        % Value changed function: BaselineWindowLowEditField
        function BaselineWindowLowEditFieldValueChanged(app, event)
            update_Base(app)
        end

        % Value changed function: BaselineWindowHighEditField
        function BaselineWindowHighEditFieldValueChanged(app, event)
            update_Base(app)
        end

        % Value changed function: ExclusionThresholdEditField
        function ExclusionThresholdEditFieldValueChanged(app, event)
            update_Base(app)
        end

        % Value changed function: 
        % ThresholdoutnegativeaveragebaselineCheckBox
        function ThresholdoutnegativeaveragebaselineCheckBoxValueChanged(app, event)
            update_Base(app)
        end

        % Value changed function: BaselineCheckBox
        function BaselineCheckBoxValueChanged(app, event)
            update_inds(app)
            update_plots(app)
        end

        % Value changed function: ABCsCheckBox
        function ABCsCheckBoxValueChanged(app, event)
            update_inds(app)
            update_plots(app)
        end

        % Callback function
        function AUCCheckBoxValueChanged(app, event)
            update_inds(app)
            update_plots(app)
        end

        % Value changed function: TKeepListBox
        function TKeepListBoxValueChanged(app, event)
            update_plots(app)
        end

        % Value changed function: TDiscListBox
        function TDiscListBoxValueChanged(app, event)
            update_plots(app)
        end

        % Button pushed function: K2DButton
        function K2DButtonPushed(app, event)
            if ~isempty(app.keep_slci)
                keep = app.keepind{app.p_currenti};
                disc = app.discind{app.p_currenti};
                k2d = keep(app.keep_slci);
                keep = setdiff(keep,k2d,'stable');
                disc = [disc,k2d];
                app.keepind{app.p_currenti} = keep;
                app.discind{app.p_currenti} = disc;
                try
                    app.TKeepListBox.Value = app.trace_names(keep(app.keep_slci));
                catch
                    try
                        app.TKeepListBox.Value = app.trace_names(keep(app.keep_slci-1));
                    catch
                        app.keep_slci = [];
                        update_plots(app)
                        return
                    end
                end
                update_plots(app)
            end
        end

        % Button pushed function: D2KButton
        function D2KButtonPushed(app, event)
            if ~isempty(app.disc_slci)
                keep = app.keepind{app.p_currenti};
                disc = app.discind{app.p_currenti};
                d2k = disc(app.disc_slci);
                disc = setdiff(disc,d2k,'stable');
                keep = [keep,d2k];
                app.keepind{app.p_currenti} = keep;
                app.discind{app.p_currenti} = disc;
                try
                    app.TDiscListBox.Value = app.trace_names(disc(app.disc_slci));
                catch
                    try
                        app.TDiscListBox.Value = app.trace_names(disc(app.disc_slci-1));
                    catch
                        app.disc_slci = [];
                        update_plots(app)
                        return
                    end
                end
                update_plots(app)
            end
        end

        % Button pushed function: SavingPathButton
        function SavingPathButtonPushed(app, event)
            app.save_folder = '';
            path = uigetdir('','Select the saving path');
            folder_name = 'Output';
            if isequal(path, 0)
                return
            else
                oldpath = cd(path);
                if ~isfolder(folder_name)
                    app.save_folder = [path,'\',folder_name];
                else
                    i = 1;
                    while isempty(app.save_folder) && i<=200
                        folder_name_new = [folder_name,'(',num2str(i),')'];
                        if ~isfolder(folder_name_new)
                            app.save_folder = [path,'\',folder_name_new];
                        end
                        i = i+1;
                    end
                end
                app.SavingPathEditField.Value = app.save_folder;
                cd(oldpath)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.save_folder)
                oldpath = cd(app.paths{end});
            else
                mkdir(app.save_folder)
                oldpath = cd(app.save_folder);
            end
            savedata(app)
            cd(oldpath)
        end

        % Value changed function: SavingPathEditField
        function SavingPathEditFieldValueChanged(app, event)
            app.save_folder = '';
            path = app.SavingPathEditField.Value;
            folder_name = 'Output';
            if isfolder(path)
                oldpath = cd(path);
                if ~isfolder(folder_name)
                    app.save_folder = [path,'\',folder_name];
                else
                    i = 1;
                    while isempty(app.save_folder) && i<=101
                        folder_name_new = [folder_name,'(',num2str(i),')'];
                        if ~isfolder(folder_name_new)
                            app.save_folder = [path,'\',folder_name_new];
                        end
                        i = i+1;
                    end
                end
                app.SavingPathEditField.Value = app.save_folder;
                cd(oldpath)
            else
                app.SavingPathEditField.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SemiAutoThresholdingUIFigure = uifigure('Visible', 'off');
            app.SemiAutoThresholdingUIFigure.Name = 'Semi-Auto Thresholding';
            app.SemiAutoThresholdingUIFigure.Icon = fullfile(pathToMLAPP, 'Sups', 'mouse.png');
            app.TKeepPlot = uiaxes(app.SemiAutoThresholdingUIFigure);
            title(app.TKeepPlot, 'Traces to Keep')
            ylabel(app.TKeepPlot, 'df/f')
            app.TDiscPlot = uiaxes(app.SemiAutoThresholdingUIFigure);
            title(app.TDiscPlot, 'Traces to Discard')
            xlabel(app.TDiscPlot, 'Frame')
            ylabel(app.TDiscPlot, 'df/f')
            app.BrowseFileButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.UITable = uitable(app.SemiAutoThresholdingUIFigure);
            app.UITable.ColumnName = {'Condition Name'; 'Neuron Count'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false];
            app.NameofConditionEditFieldLabel = uilabel(app.SemiAutoThresholdingUIFigure);
            app.NameofConditionEditFieldLabel.Text = 'Name of Condition:';
            app.NameofConditionEditField = uieditfield(app.SemiAutoThresholdingUIFigure, 'text');
            app.TabGroup_2 = uitabgroup(app.SemiAutoThresholdingUIFigure);
            app.BaselineTab = uitab(app.TabGroup_2);
            app.BaselineTab.Title = 'Baseline';
            app.BaselineTab.ButtonDownFcn = createCallbackFcn(app, @BaselineTabButtonDown, true);
            app.BaselineWindowEditField_2Label = uilabel(app.BaselineTab);
            app.BaselineWindowEditField_2Label.Text = 'Baseline Window:';
            app.BaselineWindowLowEditField = uieditfield(app.BaselineTab, 'numeric');
            app.BaselineWindowLowEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowLowEditFieldValueChanged, true);
            app.BaselineWindowLowEditField.Value = 1;
            app.EditField_2Label = uilabel(app.BaselineTab);
            app.EditField_2Label.Text = '~';
            app.BaselineWindowHighEditField = uieditfield(app.BaselineTab, 'numeric');
            app.BaselineWindowHighEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineWindowHighEditFieldValueChanged, true);
            app.BaselineWindowHighEditField.Value = 35;
            app.ExclusionThresholdLabel = uilabel(app.BaselineTab);
            app.ExclusionThresholdLabel.Text = 'Exclusion Threshold (%):';
            app.ExclusionThresholdEditField = uieditfield(app.BaselineTab, 'numeric');
            app.ExclusionThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ExclusionThresholdEditFieldValueChanged, true);
            app.ExclusionThresholdEditField.Value = 35;
            app.ThresholdoutnegativeaveragebaselineCheckBox = uicheckbox(app.BaselineTab);
            app.ThresholdoutnegativeaveragebaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdoutnegativeaveragebaselineCheckBoxValueChanged, true);
            app.ThresholdoutnegativeaveragebaselineCheckBox.Text = 'Threshold out negative average baseline';
            app.ABCsTab = uitab(app.TabGroup_2);
            app.ABCsTab.Title = 'ABCs';
            app.ABCsTab.ButtonDownFcn = createCallbackFcn(app, @ABCsTabButtonDown, true);
            app.ABCs_Plot = uiaxes(app.ABCsTab);
            app.ABCs_Plot.XTick = [];
            app.ABCs_Plot.YTick = [];
            app.ABCs_Plot.ZTick = [];
            app.ThresholdLabel = uilabel(app.ABCsTab);
            app.ThresholdLabel.Text = 'Order of Polyfit:';
            app.OrderofPolyfitEditField = uieditfield(app.ABCsTab, 'numeric');
            app.OrderofPolyfitEditField.ValueChangedFcn = createCallbackFcn(app, @OrderofPolyfitEditFieldValueChanged, true);
            app.OrderofPolyfitEditField.Value = 4;
            app.UpDriftLabel = uilabel(app.ABCsTab);
            app.UpDriftLabel.Text = 'Drift (%)';
            app.DriftEditField = uieditfield(app.ABCsTab, 'numeric');
            app.DriftEditField.ValueChangedFcn = createCallbackFcn(app, @DriftEditFieldValueChanged, true);
            app.ModeLabel = uilabel(app.ABCsTab);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.ABCsTab);
            app.ModeDropDown.Items = {'Summation', 'Moving Max'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Summation';
            app.WindowformovingEditFieldLabel = uilabel(app.ABCsTab);
            app.WindowformovingEditFieldLabel.Text = 'Window (for moving):';
            app.WindowformovingEditField = uieditfield(app.ABCsTab, 'numeric');
            app.WindowformovingEditField.ValueChangedFcn = createCallbackFcn(app, @WindowformovingEditFieldValueChanged, true);
            app.WindowformovingEditField.Value = 10;
            app.OptionsTab = uitab(app.TabGroup_2);
            app.OptionsTab.Title = 'Options';
            app.BaselineCheckBox = uicheckbox(app.OptionsTab);
            app.BaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineCheckBoxValueChanged, true);
            app.BaselineCheckBox.Text = 'Baseline';
            app.BaselineCheckBox.Value = true;
            app.ABCsCheckBox = uicheckbox(app.OptionsTab);
            app.ABCsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ABCsCheckBoxValueChanged, true);
            app.ABCsCheckBox.Text = 'ABCs';
            app.ABCsCheckBox.Value = true;
            app.BaselineLabel = uilabel(app.SemiAutoThresholdingUIFigure);
            app.BaselineLabel.Text = 'Paradigm Window:';
            app.ParadigmwindowlowEditField = uieditfield(app.SemiAutoThresholdingUIFigure, 'numeric');
            app.ParadigmwindowlowEditField.Value = 1;
            app.EditFieldLabel = uilabel(app.SemiAutoThresholdingUIFigure);
            app.EditFieldLabel.Text = '~';
            app.ParadigmwindowhighEditField = uieditfield(app.SemiAutoThresholdingUIFigure, 'numeric');
            app.ParadigmwindowhighEditField.Value = 200;
            app.ApplyParadigmButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.ApplyParadigmButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParadigmButtonPushed, true);
            app.ApplyParadigmButton.Text = 'Apply';
            app.NameofParadigmEditFieldLabel = uilabel(app.SemiAutoThresholdingUIFigure);
            app.NameofParadigmEditFieldLabel.Text = 'Name of Paradigm:';
            app.NameofParadigmEditField = uieditfield(app.SemiAutoThresholdingUIFigure, 'text');
            app.ListofParadigmsListBoxLabel = uilabel(app.SemiAutoThresholdingUIFigure);
            app.ListofParadigmsListBoxLabel.Text = 'List of Paradigms:';
            app.ListofParadigmsListBox = uilistbox(app.SemiAutoThresholdingUIFigure);
            app.ListofParadigmsListBox.Items = {};
            app.ListofParadigmsListBox.ValueChangedFcn = createCallbackFcn(app, @ListofParadigmsListBoxValueChanged, true);
            app.ListofParadigmsListBox.Value = {};
            app.TDiscListBox = uilistbox(app.SemiAutoThresholdingUIFigure);
            app.TDiscListBox.Items = {};
            app.TDiscListBox.ValueChangedFcn = createCallbackFcn(app, @TDiscListBoxValueChanged, true);
            app.TDiscListBox.Value = {};
            app.TKeepListBox = uilistbox(app.SemiAutoThresholdingUIFigure);
            app.TKeepListBox.Items = {};
            app.TKeepListBox.ValueChangedFcn = createCallbackFcn(app, @TKeepListBoxValueChanged, true);
            app.TKeepListBox.Value = {};
            app.D2KButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.D2KButton.ButtonPushedFcn = createCallbackFcn(app, @D2KButtonPushed, true);
            app.D2KButton.Icon = fullfile(pathToMLAPP, 'Sups', 'up_arrow.png');
            app.D2KButton.Text = '';
            app.K2DButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.K2DButton.ButtonPushedFcn = createCallbackFcn(app, @K2DButtonPushed, true);
            app.K2DButton.Icon = fullfile(pathToMLAPP, 'Sups', 'down_arrow.png');
            app.K2DButton.Text = '';
            app.SavingPathEditField = uieditfield(app.SemiAutoThresholdingUIFigure, 'text');
            app.SavingPathEditField.ValueChangedFcn = createCallbackFcn(app, @SavingPathEditFieldValueChanged, true);
            app.SavingPathButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.SavingPathButton.ButtonPushedFcn = createCallbackFcn(app, @SavingPathButtonPushed, true);
            app.SavingPathButton.Text = 'Saving Path:';
            app.SaveButton = uibutton(app.SemiAutoThresholdingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.SemiAutoThresholdingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thresholding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SemiAutoThresholdingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SemiAutoThresholdingUIFigure)
        end
    end
end